classdef geometry < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        EditgeometryPanel           matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        Tree                        matlab.ui.container.CheckBoxTree
        rocketNode                  matlab.ui.container.TreeNode
        UIAxes                      matlab.ui.control.UIAxes
        GridLayout2                 matlab.ui.container.GridLayout
        ClearButton                 matlab.ui.control.StateButton
        saveasgeometryconfigButton  matlab.ui.control.Button
        loadgeometryconfigButton    matlab.ui.control.Button
        importnewstepButton         matlab.ui.control.Button
    end

    properties (Access = public)
        last_folder
        rocket_default_values = struct();
        rocket_geometry = struct();
        rocket_overloaded_values = struct();
    end
    
    
    methods (Access = private)


        function load_geometry_config(comp)
            [filename, location] = uigetfile();
            comp.last_folder     = location;
            [l,f] = matrocketloadingbar();
            
            loadstruct = load([location, filename],"rocket_default_values", "rocket_prebaked", "rocket_overloaded_values");
            
            comp.rocket_geometry          = loadstruct.rocket_prebaked;
            comp.rocket_default_values    = loadstruct.rocket_default_values;
            comp.rocket_overloaded_values = loadstruct.rocket_overloaded_values;
            
            comp.update_nodetree();
            comp.update_rocket_geometry();
            
            delete(l);delete(f);
        end

        function import_geometry_step(comp)

            [filename, location]       = uigetfile('*.step');
            comp.last_folder           = location;
            path                       = erase(string(mfilename("fullpath")), "\tabs\geometry");
            [l,f] = matrocketloadingbar("Triangulating .step, this can take a while...");
            rocket                     = importSTEP([location,filename]);
            comp.rocket_default_values = rocket;
            
            comp.update_nodetree();
            comp.update_rocket_geometry();
            
            delete(l);delete(f);
        end


        function save_geometry_config(comp)

            rocket_prebaked            = comp.rocket_geometry;
            rocket_default_values      = comp.rocket_default_values;
            rocket_overloaded_values   = comp.rocket_overloaded_values;
            
            uisave({"rocket_prebaked", "rocket_default_values", "rocket_overloaded_values"}, "geometry_config.mat");

        end


        function draw_rocket_geometry(comp)
            checkedNodes = comp.Tree.CheckedNodes;
            partnames = arrayfun(@(node) node.NodeData ,checkedNodes, "UniformOutput",false);
            draw_rocket(comp.UIAxes, comp.rocket_geometry, "LineStyle", "none", "FaceAlpha", 0.5, "ColorMode", @(~,address)string2rngvec(address), "DrawCondition", @(~, address) sum(cellfun(@(el) el == address, partnames )) ~= 0 );
            
        end


        function update_nodetree(comp)
            delete(comp.Tree.Children);
            delete(comp.EditgeometryPanel.Children);
            rocket = comp.rocket_default_values;
            rocketnode = uitreenode(comp.Tree, "Text","rocket", "NodeData", "");
            update_nodetree_internal(rocketnode,rocket, "");
            comp.Tree.CheckedNodes = [rocketnode];
            expand(rocketnode);
            comp.update_editfields()
            
            
            function update_nodetree_internal(node,body,address)
                child_names = fieldnames(body);
                for child_index = 1:numel(child_names)
                    child_name = child_names{child_index};
                    if isstruct(body.(child_name))
                        child_node = uitreenode(node, "Text",child_name, "NodeData",address+"."+child_name);
                        update_nodetree_internal(child_node,body.(child_name),address+"."+child_name);
                    end
                end
            end

        end
        
        
        function update_editfields(comp)
            checkedNodes         = comp.Tree.CheckedNodes;
            partnames            = arrayfun(@(node) node.NodeData ,checkedNodes, "UniformOutput",false);
            delete(comp.EditgeometryPanel.Children);

            yoffset              = 10;
            editfield_index      = 1;

            update_editfield_internal(comp.rocket_default_values, "");
            
            if editfield_index >= 20
                uilabel(comp.EditgeometryPanel,"Text","More than 20 parameters. Hide to show more..." , "Position", [10,yoffset,450,20], "FontName","Consolas");
            else
                uilabel(comp.EditgeometryPanel,"Text","Unhide to show more..." , "Position", [10,yoffset,450,20], "FontName","Consolas");
            end

            function update_editfield_internal(body,address)
    
                address_split    = split(address, ".");
                address_split    = address_split(2:end);
                child_names      = fieldnames(body);
                child_structs    = {};
                child_parameters = {};
                struct_index     = 1;
                parameter_index  = 1;

                for child_index = 1:numel(child_names)
                    child_name = child_names{child_index};
                    if isstruct(body.(child_name))
                        child_structs{struct_index} = child_name;
                        struct_index = struct_index+1;
                    else
                        child_parameters{parameter_index} = child_name;
                        parameter_index = parameter_index+1;
                    end
                end


                for child_index = 1:numel(child_parameters)
                    child_name = child_parameters{child_index};
                    if editfield_index < 20
                        if sum(cellfun(@(el) el == address, partnames )) ~= 0 % if part is checked  
                            
                            try
                            
                                parameter_name_length = 30;
                                parameter_name = char("rocket"+address+"."+child_name);
                                if numel(parameter_name) > parameter_name_length; parameter_name = ['...',parameter_name(numel(parameter_name)-parameter_name_length+4:end)];
                                else;                                             parameter_name = [parameter_name, repmat(' ', 1,parameter_name_length-numel(parameter_name))];
                                end
                                
                                defaultvalue = char(jsonencode(body.(child_name)));
                                defaultvalue_length = 30;
            
                                if numel(defaultvalue) > defaultvalue_length; defaultvalue = ['...',defaultvalue(numel(defaultvalue)-defaultvalue_length+4:end)];
                                else;                                         defaultvalue = [defaultvalue, repmat(' ', 1,defaultvalue_length-numel(defaultvalue))];
                                end
            
            
                                fullstr                   = string(parameter_name)+" : "+string(defaultvalue) + " : ";
                                child_editfield           = uieditfield(comp.EditgeometryPanel, "text", "Position", [480,yoffset,260,20], 'ValueChangedFcn', @comp.update_rocket_geometry);
                                child_editfield.UserData  = "rocket"+address+"."+child_name;
                                child_label               = uilabel(comp.EditgeometryPanel,"Text",fullstr , "Position", [10,yoffset,450,20], "FontName","Consolas");
                                yoffset                   = yoffset+30;
                                editfield_index           = editfield_index +1;
                                
                                if numel(address_split) > 0
        
                                    child_editfield.Value = jsonencode(getfield(comp.rocket_overloaded_values, address_split{:}, child_name));
                                    child_editfield.BackgroundColor = [0.7, 1, 0.7];

                                else
                                    child_editfield.Value = jsonencode(getfield(comp.rocket_overloaded_values, child_name));
                                    child_editfield.BackgroundColor = [0.7, 1, 0.7];
        
                                end
                            catch
                            end
                        end
                    end
                end

                for child_index = 1:numel(child_structs)    
                    child_name = child_structs{child_index};
                    update_editfield_internal(body.(child_name),address+"."+child_name)
                end
                
            end
        end
  

        function update_rocket_geometry(comp, src, ~)
            [l,f] = matrocketloadingbar();
            if exist("src", "var")
    
                address_split = cellstr(split(src.UserData, "."));
                address_split = address_split(2:end);
    
                try
    
                    if ~ isempty(src.Value)
                    if ~isequal(size(getfield(comp.rocket_default_values, address_split{:})), size(jsondecode(src.Value)))
                        matrocketerror("Dimensions must agree");
                        src.Value = "";
                        src.BackgroundColor = [1, 1, 1];
                        return
                    elseif ~isequal(class(getfield(comp.rocket_default_values, address_split{:})), class(jsondecode(src.Value)))
                        matrocketerror("Datatype myst agree");
                        src.Value = "";
                        src.BackgroundColor = [1, 1, 1];
                        return
                    end
                    end
                    
        
                        if isempty(src.Value)
                        comp.rocket_overloaded_values = rmfield_deep(comp.rocket_overloaded_values, address_split);
                        src.BackgroundColor = [1, 1, 1];
                        struct_printout(comp.rocket_overloaded_values)
                        else
                        comp.rocket_overloaded_values = setfield_safe(comp.rocket_overloaded_values, address_split, jsondecode(src.Value));
                        src.BackgroundColor = [0.2, 1, 0.2];
                        struct_printout(comp.rocket_overloaded_values)
                        end
                
                catch ME
                        matrocketerror("Must be valid json syntax")
                        src.Value = "";
                        src.BackgroundColor = [1, 1, 1];
                        disp(ME.message)
                        return
                end
            end
            comp.rocket_geometry = struct_append(comp.rocket_default_values, comp.rocket_overloaded_values);
            comp.draw_rocket_geometry()
            delete(l);delete(f);
        end

    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.last_folder              = erase(string(mfilename("fullpath")), "Core\MatRocketUI\tabs\geometry");
            comp.rocket_geometry          = struct();
            comp.rocket_default_values    = struct();
            comp.rocket_overloaded_values = struct();
            comp.update_nodetree()
            comp.draw_rocket_geometry()
        end

        % Button pushed function: importnewstepButton
        function importnewstepButtonPushed(comp, event)
            comp.import_geometry_step();
        end

        % Button pushed function: loadgeometryconfigButton
        function loadgeometryconfigButtonPushed(comp, event)
            comp.load_geometry_config();
        end

        % Button pushed function: saveasgeometryconfigButton
        function saveasgeometryconfigButtonPushed(comp, event)
            comp.save_geometry_config();
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(comp, event)
            comp.draw_rocket_geometry()
            comp.update_editfields()
        end

        % Value changed function: ClearButton
        function ClearButtonValueChanged(comp, event)
            value = comp.ClearButton.Value;
            comp.postSetupFcn();
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1318 740];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.importnewstepButton = uibutton(comp.GridLayout2, 'push');
            comp.importnewstepButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @importnewstepButtonPushed, true);
            comp.importnewstepButton.Text = 'import new .step';
            comp.loadgeometryconfigButton = uibutton(comp.GridLayout2, 'push');
            comp.loadgeometryconfigButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @loadgeometryconfigButtonPushed, true);
            comp.loadgeometryconfigButton.Text = 'load geometry config';
            comp.saveasgeometryconfigButton = uibutton(comp.GridLayout2, 'push');
            comp.saveasgeometryconfigButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @saveasgeometryconfigButtonPushed, true);
            comp.saveasgeometryconfigButton.Text = 'save as geometry config';
            comp.ClearButton = uibutton(comp.GridLayout2, 'state');
            comp.ClearButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ClearButtonValueChanged, true);
            comp.ClearButton.Text = 'Clear';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.UIAxes = uiaxes(comp.GridLayout3);
            title(comp.UIAxes, 'CAD')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.GridLayout4 = uigridlayout(comp.GridLayout3);
            comp.Tree = uitree(comp.GridLayout4, 'checkbox');
            comp.rocketNode = uitreenode(comp.Tree);
            comp.rocketNode.Text = 'rocket';
            % Assign Checked Nodes
            comp.Tree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @TreeCheckedNodesChanged, true);
            comp.EditgeometryPanel = uipanel(comp.GridLayout3);
            comp.EditgeometryPanel.Title = 'Edit geometry';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end