classdef modelling < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        Tree                           matlab.ui.container.CheckBoxTree
        rocketNode                     matlab.ui.container.TreeNode
        ModelsPanel                    matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        LoadedmodelsPanel              matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        ListBoxLoaded                  matlab.ui.control.ListBox
        ActivemodelsPanel              matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        ListBoxActive                  matlab.ui.control.ListBox
        GridLayout10_2                 matlab.ui.container.GridLayout
        ApplyButton                    matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        GridLayout10                   matlab.ui.container.GridLayout
        editButton                     matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        newButton                      matlab.ui.control.Button
        importButton                   matlab.ui.control.Button
        EditinitialvaluesPanel         matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ClearButton                    matlab.ui.control.StateButton
        saveasinitialvaluesconfigButton  matlab.ui.control.Button
        loadinitialvaluesconfigButton  matlab.ui.control.Button
        importprebakedconfigButton     matlab.ui.control.Button
    end

    properties (Access = private)
        last_folder
    end
    
    properties (Access = public)
        active_models
        loaded_models
        path
        rocket_default_values = struct();
        rocket_initial_values = struct();
        rocket_overloaded_values = struct();
        rocket_prebaked = struct();
    end
    
    
    methods (Access = private)


        function load_initial_values_config(comp)
            
            [filename, location] = uigetfile();
            comp.last_folder = location;
            
            [l,f] = matrocketloadingbar();

            loadstruct = load([location, filename],"rocket_default_values", "rocket_prebaked", "rocket_overloaded_values", "rocket_initial_values", "active_models", "loaded_models");
            
            comp.rocket_default_values    = loadstruct.rocket_default_values;
            comp.rocket_prebaked          = loadstruct.rocket_prebaked;
            comp.rocket_overloaded_values = loadstruct.rocket_overloaded_values;
            comp.rocket_initial_values    = loadstruct.rocket_initial_values;
            comp.active_models            = loadstruct.active_models;
            comp.loaded_models            = loadstruct.loaded_models;
            
            comp.refresh_active_models();
            comp.refresh_loaded_models();
            comp.update_nodetree();
            delete(l);delete(f);
        end

        function import_prebaked_config(comp)
            [l,f] = matrocketloadingbar();
            [filename, location]       = uigetfile();
            comp.last_folder           = location;
            comp.rocket_prebaked       = load([location, filename],"rocket_prebaked").rocket_prebaked;
            comp.apply_models()
            delete(l);delete(f);


        end


        function save_initial_values_config(comp)
            
            rocket_prebaked           = comp.rocket_prebaked;
            rocket_overloaded_values  = comp.rocket_overloaded_values;
            rocket_default_values     = comp.rocket_default_values;
            rocket_initial_values     = comp.rocket_initial_values;
            active_models             = comp.active_models;
            loaded_models             = comp.loaded_models;

            uisave({"rocket_prebaked", "rocket_overloaded_values", "rocket_default_values", "rocket_initial_values", "active_models", "loaded_models"}, "initial_values_config.mat");

        end


        function update_nodetree(comp)
            delete(comp.Tree.Children);
            delete(comp.EditinitialvaluesPanel.Children);
            rocket = rm_nonjsonfields(comp.rocket_default_values);
            rocketnode = uitreenode(comp.Tree, "Text","rocket", "NodeData", "");
            update_nodetree_internal(rocketnode,rocket, "");
            comp.Tree.CheckedNodes = [rocketnode];
            expand(rocketnode);
            comp.update_editfields()
            
            
            function update_nodetree_internal(node,body,address)
                child_names = fieldnames(body);
                for child_index = 1:numel(child_names)
                    child_name = child_names{child_index};
                    if isstruct(body.(child_name)) && isscalar(body.(child_name))
                        child_node = uitreenode(node, "Text",child_name, "NodeData",address+"."+child_name);
                        update_nodetree_internal(child_node,body.(child_name),address+"."+child_name);
                    end
                end
            end

        end
        
        
        function update_editfields(comp)
            checkedNodes = comp.Tree.CheckedNodes;
            partnames = arrayfun(@(node) node.NodeData ,checkedNodes, "UniformOutput",false);
            delete(comp.EditinitialvaluesPanel.Children);

            yoffset = 10;
            update_editfield_internal(comp.rocket_default_values, "");

            function update_editfield_internal(body,address)
                address_split = split(address, ".");
                address_split = address_split(2:end);
                child_names = fieldnames(body);
                child_structs    = {};
                child_parameters = {};
                struct_index     = 1;
                parameter_index  = 1;

                for child_index = 1:numel(child_names)
                    child_name = child_names{child_index};
                    if isstruct(body.(child_name)) && isscalar(body.(child_name))
                        child_structs{struct_index} = child_name;
                        struct_index = struct_index+1;
                    else
                        child_parameters{parameter_index} = child_name;
                        parameter_index = parameter_index+1;
                    end
                end


                for child_index = 1:numel(child_structs)    
                    child_name = child_structs{child_index};
                    update_editfield_internal(body.(child_name),address+"."+child_name)
                end

                for child_index = 1:numel(child_parameters)    
                    child_name = child_parameters{child_index};

                    if sum(cellfun(@(el) el == address, partnames )) ~= 0 % if part is checked  
                        
                        try

                            parameter_name_length = 30;
                            parameter_name = char("rocket"+address+"."+child_name);
                            if numel(parameter_name) > parameter_name_length; parameter_name = ['...',parameter_name(numel(parameter_name)-parameter_name_length+4:end)];
                            else;                                             parameter_name = [parameter_name, repmat(' ', 1,parameter_name_length-numel(parameter_name))];
                            end
                            
                            defaultvalue = char(jsonencode(body.(child_name)));
                            defaultvalue_length = 30;
        
                            if numel(defaultvalue) > defaultvalue_length;     defaultvalue   = ['...',defaultvalue(numel(defaultvalue)-defaultvalue_length+4:end)];
                            else;                                             defaultvalue   = [defaultvalue, repmat(' ', 1,defaultvalue_length-numel(defaultvalue))];
                            end
        
        
                            fullstr = string(parameter_name)+" : "+string(defaultvalue) + " : ";
                            child_editfield          = uieditfield(comp.EditinitialvaluesPanel, "text", "Position", [480,yoffset,260,20], 'ValueChangedFcn', @comp.update_rocket_initial_values);
                            child_editfield.UserData = "rocket"+address+"."+child_name;
                            child_label              = uilabel(comp.EditinitialvaluesPanel,"Text",fullstr , "Position", [10,yoffset,450,20], "FontName","Consolas");
                            yoffset = yoffset+30;


                            if numel(address_split) > 0
    
                                child_editfield.Value = jsonencode(getfield(comp.rocket_overloaded_values, address_split{:}, child_name));
                                child_editfield.BackgroundColor = [0.7, 1, 0.7];
                                
                            else
                                child_editfield.Value = jsonencode(getfield(comp.rocket_overloaded_values, child_name));
                                child_editfield.BackgroundColor = [0.7, 1, 0.7];
    
                            end
                        catch ME
                        end
                    end
                end
            end
        end
  

        function update_rocket_initial_values(comp, src, ~)
            address_split = cellstr(split(src.UserData, "."));
            address_split = address_split(2:end);

            try

                if ~ isempty(src.Value)
                if ~isequal(class(getfield(comp.rocket_default_values, address_split{:})), class(jsondecode(src.Value)))
                    matrocketerror("Datatype must agree")
                    src.Value = "";
                    src.BackgroundColor = [1, 1, 1];
                    return
                end
                end
                
    
                    if isempty(src.Value)
                    comp.rocket_overloaded_values = rmfield_deep(comp.rocket_overloaded_values, address_split);
                    src.BackgroundColor = [1, 1, 1];
                    struct_printout(comp.rocket_overloaded_values)
                    else
                    comp.rocket_overloaded_values = setfield_safe(comp.rocket_overloaded_values, address_split, jsondecode(src.Value));
                    src.BackgroundColor = [0.2, 1, 0.2];
                    struct_printout(comp.rocket_overloaded_values)
                    end
            
            catch ME
                    matrocketerror("Must be valid json syntax")
                    src.Value = "";
                    src.BackgroundColor = [1, 1, 1];
                    disp(ME.message)
                    return   
            end
            
            comp.rocket_initial_values = struct_append(comp.rocket_default_values, comp.rocket_overloaded_values);
        end


        function import_models(comp, model_paths)
        
            for model_index = 1:numel(model_paths)
                addpath(model_paths{model_index}.folder)
                if endsWith(model_paths{model_index}.name, ".m")
                    name = erase(model_paths{model_index}.name, ".m");
                    comp.loaded_models(name) = model_paths{model_index}.folder;
                end
            end
            comp.refresh_loaded_models()
        end


        function refresh_loaded_models(comp)

            comp.ListBoxLoaded.Items = {};
            models = comp.loaded_models.keys;
            
            for model_index = 1:numel(models)
                name   = models{model_index};
                folder = comp.loaded_models(name);
                
                if isfile(folder+"\"+name+".m")
                    addpath(folder)
                    comp.ListBoxLoaded.Items{end+1} = name;
                else
                    remove(comp.loaded_models, name)
                end
            
            end
        end


        function refresh_active_models(comp)

            comp.ListBoxActive.Items = {};
            
            for model_index = 1:numel(comp.active_models)
                name   = comp.active_models{model_index};
                
                comp.ListBoxActive.Items{end+1} = name;

            end
        end


        function apply_models(comp)

            models_string = "{";
            for model_index = 1:numel(comp.active_models)
                models_string = models_string + " @" + comp.active_models{model_index};
            end
            models_string                  = models_string + "}";
            comp.rocket_prebaked.models    = eval(models_string);
            eval(models_string)

            comp.rocket_default_values     = bake_rocket(comp.rocket_prebaked, true);
            comp.rocket_overloaded_values  = struct_trim(comp.rocket_overloaded_values, comp.rocket_default_values);
            comp.rocket_initial_values     = struct_append(comp.rocket_default_values, comp.rocket_overloaded_values);
            comp.update_nodetree();
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.path                     = erase(string(mfilename("fullpath")), "\tabs\modelling");
            comp.last_folder              = erase(string(mfilename("fullpath")), "Core\MatRocketUI\tabs\modelling");
            comp.loaded_models            = containers.Map();
            comp.rocket_initial_values    = struct();
            comp.rocket_default_values    = struct();
            comp.rocket_overloaded_values = struct();
            comp.rocket_prebaked          = struct();
            comp.ListBoxLoaded.Items      = {};
            comp.active_models            = { ...
                                         'base_manual_atmosphere_model',     ...
                                         'base_drag_coefficient_model',      ...
                                         'base_aerodynamics_model',          ...
                                         'base_hybrid_propulsion_model',     ...
                                         'base_gravity_model',               ...
                                         'base_dual_deploy_parachute_model', ...
                                         'base_equations_of_motion_model',   ...
                                         'base_ground_interaction_model'     ...
                                         };
            
            base_models                   = num2cell(dir(erase(comp.path, "\MatRocketUI")+"\Models"));
            base_models                   = base_models(3:end);
            comp.import_models(base_models);
            comp.refresh_active_models();
            comp.update_nodetree();


        end

        % Button pushed function: importprebakedconfigButton
        function importprebakedconfigButtonPushed2(comp, event)
            comp.import_prebaked_config();
        end

        % Button pushed function: loadinitialvaluesconfigButton
        function loadinitialvaluesconfigButtonPushed(comp, event)
            comp.load_initial_values_config();
        end

        % Button pushed function: saveasinitialvaluesconfigButton
        function saveasinitialvaluesconfigButtonPushed(comp, event)
            comp.save_initial_values_config();
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(comp, event)

            comp.update_editfields()
        end

        % Button pushed function: importButton
        function importButtonPushed(comp, event)
            [files, folder]  = uigetfile('*.m','MultiSelect', 'on');
            comp.last_folder = folder;
            if ~iscell(files); files = {files}; end
            models = cell(1,numel(files)); 
            for model_index = 1:numel(files)
            models{model_index} = struct("name", files{model_index}, "folder", folder);
            end
            comp.import_models(models)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(comp, event)
            if sum(cellfun(@(m) isequal(m,comp.ListBoxLoaded.Value), comp.active_models)) == 0
            comp.active_models{end+1} = comp.ListBoxLoaded.Value;
            comp.refresh_active_models()
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(comp, event)
            comp.apply_models()
        end

        % Button pushed function: newButton
        function newButtonPushed(comp, event)
            [file, folder] = uiputfile('*.m', 'Create model');
            if isequal(file,0)
                return
            end
        
            lines = {
                ['function rocket =', char(erase(string(file), ".m")),'(rocket, declare_dependencies)']
                '   % Generic model'
                ''
                ''
                '   if declare_dependencies'
                ''
                '     %% Model dependencies go here! These will be the default initial values, can be overwritten by user'
                ''
                ''
                ''
                '   else'
                ''
                '       %% Modelling and equations to be executed throughout simulation go here.'
                ''
                ''
                ''
                '   end'

                'end'
            };
        
            fid = fopen(fullfile(folder,file),'w');
            fprintf(fid,'%s\n',lines{:});
            fclose(fid);
        
            edit(fullfile(folder,file))
        end

        % Button pushed function: editButton
        function editButtonPushed(comp, event)
            open(comp.ListBoxLoaded.Value)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(comp, event)
            
            index = comp.ListBoxActive.ValueIndex;
            comp.active_models = {comp.active_models{1:index-1}, comp.active_models{index+1:end}};
            comp.refresh_active_models()

        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            index = find(cellfun(@(val) isequal(string(comp.ListBoxActive.Value), string(val)), comp.active_models));
            
            if index == 2
                comp.active_models = {comp.active_models{2}, comp.active_models{1}, comp.active_models{3:end}};
            elseif index > 2
                comp.active_models = {comp.active_models{1:index-2}, comp.active_models{index},comp.active_models{index-1}, comp.active_models{index+1:end}};
            end
            comp.refresh_active_models()
            comp.ListBoxActive.Value = comp.active_models{index-1}; 
        end

        % Button pushed function: Button_2
        function Button_2Pushed(comp, event)
            index = find(cellfun(@(val) isequal(string(comp.ListBoxActive.Value), string(val)), comp.active_models));
            
            if index == numel(comp.active_models)-1
                comp.active_models = {comp.active_models{1:end-2}, comp.active_models{end}, comp.active_models{end-1}};
            elseif index < numel(comp.active_models)-1
                comp.active_models = {comp.active_models{1:index-1}, comp.active_models{index+1},comp.active_models{index}, comp.active_models{index+2:end}};
            end
            comp.refresh_active_models()
            comp.ListBoxActive.Value = comp.active_models{index+1};
        end

        % Value changed function: ClearButton
        function ClearButtonValueChanged(comp, event)
            value = comp.ClearButton.Value;
            comp.postSetupFcn()
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1248 688];
            comp.BackgroundColor = [0.3529 0.0745 0.4314];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.importprebakedconfigButton = uibutton(comp.GridLayout2, 'push');
            comp.importprebakedconfigButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @importprebakedconfigButtonPushed2, true);
            comp.importprebakedconfigButton.Text = 'import pre-baked config';
            comp.loadinitialvaluesconfigButton = uibutton(comp.GridLayout2, 'push');
            comp.loadinitialvaluesconfigButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @loadinitialvaluesconfigButtonPushed, true);
            comp.loadinitialvaluesconfigButton.Text = 'load initial values config';
            comp.saveasinitialvaluesconfigButton = uibutton(comp.GridLayout2, 'push');
            comp.saveasinitialvaluesconfigButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @saveasinitialvaluesconfigButtonPushed, true);
            comp.saveasinitialvaluesconfigButton.Text = 'save as initial values config';
            comp.ClearButton = uibutton(comp.GridLayout2, 'state');
            comp.ClearButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ClearButtonValueChanged, true);
            comp.ClearButton.Text = 'Clear';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.EditinitialvaluesPanel = uipanel(comp.GridLayout3);
            comp.EditinitialvaluesPanel.Title = 'Edit initial values';
            comp.ModelsPanel = uipanel(comp.GridLayout3);
            comp.ModelsPanel.Title = 'Models';
            comp.GridLayout5 = uigridlayout(comp.ModelsPanel);
            comp.GridLayout10 = uigridlayout(comp.GridLayout5);
            comp.importButton = uibutton(comp.GridLayout10, 'push');
            comp.importButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @importButtonPushed, true);
            comp.importButton.Text = 'import';
            comp.newButton = uibutton(comp.GridLayout10, 'push');
            comp.newButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @newButtonPushed, true);
            comp.newButton.Text = '*new';
            comp.Button_4 = uibutton(comp.GridLayout10, 'push');
            comp.Button_4.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_4Pushed, true);
            comp.Button_4.Text = '+';
            comp.editButton = uibutton(comp.GridLayout10, 'push');
            comp.editButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @editButtonPushed, true);
            comp.editButton.Text = '*edit';
            comp.GridLayout10_2 = uigridlayout(comp.GridLayout5);
            comp.Button_3 = uibutton(comp.GridLayout10_2, 'push');
            comp.Button_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_3Pushed, true);
            comp.Button_3.Text = '-';
            comp.Button = uibutton(comp.GridLayout10_2, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Text = '↑';
            comp.Button_2 = uibutton(comp.GridLayout10_2, 'push');
            comp.Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Button_2Pushed, true);
            comp.Button_2.Text = '↓';
            comp.ApplyButton = uibutton(comp.GridLayout10_2, 'push');
            comp.ApplyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ApplyButtonPushed, true);
            comp.ApplyButton.Text = 'Apply';
            comp.ActivemodelsPanel = uipanel(comp.GridLayout5);
            comp.ActivemodelsPanel.Title = 'Active models';
            comp.GridLayout9 = uigridlayout(comp.ActivemodelsPanel);
            comp.ListBoxActive = uilistbox(comp.GridLayout9);
            comp.LoadedmodelsPanel = uipanel(comp.GridLayout5);
            comp.LoadedmodelsPanel.Title = 'Loaded models';
            comp.GridLayout8 = uigridlayout(comp.LoadedmodelsPanel);
            comp.ListBoxLoaded = uilistbox(comp.GridLayout8);
            comp.Tree = uitree(comp.GridLayout3, 'checkbox');
            comp.rocketNode = uitreenode(comp.Tree);
            comp.rocketNode.Text = 'rocket';
            % Assign Checked Nodes
            comp.Tree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @TreeCheckedNodesChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end