classdef results < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout3                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        GeoplotTab                     matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        LatitudeEditField              matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel         matlab.ui.control.Label
        LongitudeEditField             matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        PlotTab                        matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        Tree                           matlab.ui.container.CheckBoxTree
        UIAxes                         matlab.ui.control.UIAxes
        DPlotTab                       matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        GridLayout12                   matlab.ui.container.GridLayout
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        GridLayout10_2                 matlab.ui.container.GridLayout
        Tree3                          matlab.ui.container.CheckBoxTree
        GridLayout10                   matlab.ui.container.GridLayout
        Tree2                          matlab.ui.container.CheckBoxTree
        importgeometryconfigButton     matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        ClearButton                    matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        Button                         matlab.ui.control.Button
        importsimulationconfigsButton  matlab.ui.control.Button
    end

    properties (Access = public)
        colormap
        geoax
        last_folder
        path
        rocket_geometry
        rocket_plottables
        simulations = {};
    end
    
    
    methods (Access = private)


        function import_initial_values_configs(comp)
            [files, folder] = uigetfile('*.mat','MultiSelect', 'on');
            comp.last_folder = folder;
            if ~iscell(files); files = {files}; end
            for simulation_index = 1:numel(files)
                load([folder, files{simulation_index}], "-mat", "rocket_initial_values", "rocket_results");
                comp.simulations{end+1}                              = struct();
                comp.simulations{end  }.rocket_initial_values        = rocket_initial_values;
                comp.simulations{end  }.rocket_initial_values_file   = files{simulation_index};
                comp.simulations{end  }.rocket_initial_values_folder = folder;
                comp.simulations{end  }.rocket_results               = rocket_results;

            end
            comp.simulations
            comp.update_simulations_list()
        
        end


        function import_geometry_config(comp)
            [filename, location]       = uigetfile("*.mat");
            comp.last_folder           = location;
            comp.rocket_geometry       = load([location, filename],"rocket_prebaked").rocket_prebaked;
            comp.update_geometry_nodetree();
            comp.draw_geometry();
            comp.draw_3dplots();

        end


        function update_simulations_list(comp)

            comp.ListBox.Items     = {};
            comp.rocket_plottables = struct();
            numsims                = numel(comp.simulations);
            
            for simulation_index = 1:numsims

                comp.ListBox.Items{end+1} = [comp.simulations{simulation_index}.rocket_initial_values_file , ' (done)'];
                comp.rocket_plottables    = struct_append(comp.rocket_plottables, comp.simulations{simulation_index}.rocket_results);
    
            end

            
            color2 = [200, 0, 250]/255;
            color1 = [255, 100, 0]/255;

            if numsims == 1
                comp.colormap = @(index) color1;
            elseif numsims > 1
                comp.colormap = @(index) interp1((1:numsims)', [linspace(color1(1),color2(1),numsims); ...
                                                                linspace(color1(2),color2(2),numsims); ...
                                                                linspace(color1(3),color2(3),numsims)]', index);
            end

            comp.draw_geoplot_trajectories()
            comp.draw_2dplots()
            comp.draw_geometry()
            comp.draw_3dplots()
            comp.update_nodetree(comp.rocket_plottables, comp.Tree)
            comp.update_nodetree(comp.rocket_plottables, comp.Tree3)

        end


        function update_nodetree(comp, rocket, tree)
            delete(tree.Children);
            rocket = rm_nonjsonfields(rocket);
            basenode = update_nodetree_internal(tree,rocket, "","rocket");
            expand(basenode);
            
            function node = update_nodetree_internal(parent_node,body,address,name)
                if isstruct(body) || isequal(class(body), "double")
                    node = uitreenode(parent_node, "Text",name, "NodeData",address);
                    if isstruct(body)
                    child_names = fieldnames(body);
                        for child_index = 1:numel(child_names)
                            child_name = child_names{child_index};
                            update_nodetree_internal(node,body.(child_name),address+"."+child_name, child_name);
                        end
                    end
                    
                end
                
            end

        end


        function update_geometry_nodetree(comp)
            delete(comp.Tree2.Children);
            rocket = comp.rocket_geometry;
            rocketnode = uitreenode(comp.Tree2, "Text","rocket", "NodeData", "");
            update_nodetree_internal(rocketnode,rocket, "");
            comp.Tree2.CheckedNodes = [rocketnode];
            expand(rocketnode);
            
            
            function update_nodetree_internal(node,body,address)
                child_names = fieldnames(body);
                for child_index = 1:numel(child_names)
                    child_name = child_names{child_index};
                    if isstruct(body.(child_name))
                        child_node = uitreenode(node, "Text",child_name, "NodeData",address+"."+child_name);
                        update_nodetree_internal(child_node,body.(child_name),address+"."+child_name);
                    end
                end
            end

        end


        function draw_geoplot_trajectories(comp)
            hold(comp.geoax, "off")
            geoplot3(comp.geoax, NaN,NaN,NaN); 
            hold(comp.geoax, "on")
            for simulation_index = 1:numel(comp.simulations)

                if ~(isequal(class(comp.LatitudeEditField.Value), "double") && isequal(class(comp.LongitudeEditField.Value), "double"))
                    errf = uifigure("Icon",comp.path+"\Logos\IMG_7900.jpg", "Name","Error");
                    uialert(errf, "Must be float", "Error", "Icon", comp.path+"\Logos\IMG_7900.jpg")
                    comp.LatitudeEditField.Value  = 17.124351;
                    comp.LongitudeEditField.Value = 59.741332;
                    return
                end

                lla0 = [comp.LatitudeEditField.Value, comp.LongitudeEditField.Value, 0];
                rocket_results = comp.simulations{simulation_index}.rocket_results;
                vectorgeoplot3(comp.geoax, lla0, rocket_results.position, "Color",comp.colormap(simulation_index), "LineWidth", 1)
                bounding_box = [max(rocket_results.position(1,:));max(rocket_results.position(2,:));max(rocket_results.position(3,:))] - [min(rocket_results.position(1,:));min(rocket_results.position(2,:));min(rocket_results.position(3,:))];
                bounding_distance = max(bounding_box);
                vectorgeocomb(comp.geoax, lla0,reshape(rocket_results.attitude(:,3,:), 3,[],1)*bounding_distance/50+rocket_results.position,rocket_results.position,  30,"Color",comp.colormap(simulation_index), "LineWidth", 0.5)
          
            end

        end


        function draw_2dplots(comp)
            checkedNodes = comp.Tree.CheckedNodes;
            parameters   = arrayfun(@(node) node.NodeData ,checkedNodes, "UniformOutput",false);
            hold(comp.UIAxes, "off");
            plot(comp.UIAxes, NaN,NaN);
            hold(comp.UIAxes, "on");
            for simulation_index = 1:numel(comp.simulations)
                results = comp.simulations{simulation_index}.rocket_results;
                for parameter_index = 1:numel(parameters)
                    parameter_address = parameters{parameter_index};
                    address_split = split(parameter_address, ".");
                    address_split = num2cell(address_split(2:end));
                    if isfield_deep(results, address_split)
                        parameter = getfield(results, address_split{:});
                        if isequal(class(parameter), "double")
                            plot(comp.UIAxes, results.t, reshape(parameter, [],numel(results.t)), "Color",comp.colormap(simulation_index));
                            title(comp.UIAxes, strrep(address_split{end}, "_", " "))
                        end
                    end
    
                end
            end

        end


        function draw_3dplots(comp)
            contains = @(list, el) sum(cellfun(@(el2) el2 == el , list)) >= 1;
            checkedNodes = comp.Tree3.CheckedNodes;
            parameters   = arrayfun(@(node) node.NodeData ,checkedNodes, "UniformOutput",false)
            xlim = comp.UIAxes2.XLim;
            ylim = comp.UIAxes2.YLim;
            zlim = comp.UIAxes2.ZLim;
            for simulation_index = 1:numel(comp.simulations)
                results = comp.simulations{simulation_index}.rocket_results;
                for parameter_index = 1:numel(parameters)
                    parameter_address = parameters{parameter_index};
                    address_split = split(parameter_address, ".");
                    address_split = num2cell(address_split(2:end));
                    address_split = {"rocket", address_split{:}};
                    if isfield_deep(results, address_split(2:end))
                        parameter = getfield(results, address_split{2:end});
                        if isequal(class(parameter), "double") && ~isequal(address_split{end}, "position")
                        
                            if contains(parameters, strrep(parameter_address, address_split{end}, "position"))
                                position_address = {address_split{1:end-1}, "position"};
                                positions = getfield(results, position_address{2:end});
                                vectorcomb(comp.UIAxes2, positions + reshape(parameter, 3,[])*(10^comp.ScaleEditField.Value), positions, 30, "Color", comp.colormap(simulation_index));
                                vectorscatter(comp.UIAxes2, positions + reshape(parameter, 3,[])*(10^comp.ScaleEditField.Value),".","MarkerEdgeColor", comp.colormap(simulation_index));
                                title(comp.UIAxes2, strrep(address_split{end-1}, "_", " ")+" "+strrep(address_split{end}, "_", " ")+" [\cdot 1e"+string(comp.ScaleEditField.Value)+"] (position)")

                            else

                                vectorcomb(comp.UIAxes2, reshape(parameter, 3,[])*(10^comp.ScaleEditField.Value), zeros(size(reshape(parameter, 3,[]))), 30, "Color", comp.colormap(simulation_index));
                                vectorscatter(comp.UIAxes2, reshape(parameter, 3,[])*(10^comp.ScaleEditField.Value),".","MarkerEdgeColor", comp.colormap(simulation_index));
                                title(comp.UIAxes2, strrep(address_split{end-1}, "_", " ")+" "+strrep(address_split{end}, "_", " ")+" [\cdot 1e"+string(comp.ScaleEditField.Value)+"] (origin)")
                            
                            end
                        end
                    end
    
                end
            end

            comp.UIAxes2.XLim = xlim;
            comp.UIAxes2.YLim = ylim;
            comp.UIAxes2.ZLim = zlim;

        end


        function draw_geometry(comp)
            checkedNodes = comp.Tree2.CheckedNodes;
            parts        = arrayfun(@(node) node.NodeData ,checkedNodes, "UniformOutput",false);

            comp.UIAxes2.NextPlot = "replacechildren";
            plot3(comp.UIAxes2, NaN,NaN,NaN);
            comp.UIAxes2.NextPlot = "add";
            for part_index = 1:numel(parts)
                if ~ismember(checkedNodes(part_index).Parent, checkedNodes)
                    part_address = parts{part_index};
                    address_split = split(part_address, ".");
                    address_split = num2cell(address_split(2:end));
                    if numel(address_split) > 0
                        part = getfield(comp.rocket_geometry, address_split{:});
                    else
                        part = comp.rocket_geometry;
                    end
                    part.position = [0;0;0];
                    part.attitude = eye(3);
                    draw_rocket(comp.UIAxes2, part, "LineStyle", "none", "FaceAlpha", 0.15, "ColorMode", @(~,address)string2rngvec(address))
                end

            end

            comp.UIAxes2.XLim = comp.UIAxes2.XLim - mean(comp.UIAxes2.XLim);
            comp.UIAxes2.YLim = comp.UIAxes2.YLim - mean(comp.UIAxes2.YLim);
            comp.UIAxes2.ZLim = comp.UIAxes2.ZLim - mean(comp.UIAxes2.ZLim);

            comp.UIAxes2.XLim = comp.UIAxes2.XLim*6;
            comp.UIAxes2.YLim = comp.UIAxes2.YLim*6;
            comp.UIAxes2.ZLim = comp.UIAxes2.ZLim*3;


        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.geoax                    = geoglobe(comp.Panel);
            comp.path                     = erase(string(mfilename("fullpath")), "\tabs\simulation");
            comp.last_folder              = erase(string(mfilename("fullpath")), "Core\MatRocketUI\tabs\simulation");
            comp.ListBox.Items            = {};
            comp.LongitudeEditField.Value = 17.124351;
            comp.LatitudeEditField.Value  = 59.741332;
            
        end

        % Button pushed function: importsimulationconfigsButton
        function importsimulationconfigsButtonPushed(comp, event)
            [f,l] = matrocketloadingbar();
            comp.import_initial_values_configs();
            delete(f); delete(l);
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            [f,l] = matrocketloadingbar();
            index = comp.ListBox.ValueIndex;
            if numel(comp.simulations) > 1
                comp.simulations = {comp.simulations{1:index-1}, comp.simulations{index+1:end}};
                comp.ListBox.ValueIndex = index;
            else
                comp.simulations = {};
            end
            comp.update_simulations_list()
            delete(f);delete(l);
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(comp, event)
            [f,l] = matrocketloadingbar();
            checkedNodes = comp.Tree.CheckedNodes;
            comp.draw_2dplots()
            delete(f);delete(l);
        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(comp, event)
            [f,l] = matrocketloadingbar();
            value = comp.LongitudeEditField.Value;
            comp.draw_geoplot_trajectories()
            delete(f);delete(l);
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(comp, event)
            [f,l] = matrocketloadingbar();
            value = comp.LatitudeEditField.Value;
            comp.draw_geoplot_trajectories()
            delete(f);delete(l);
        end

        % Button pushed function: importgeometryconfigButton
        function importgeometryconfigButtonPushed(comp, event)
            [f,l] = matrocketloadingbar();
            comp.import_geometry_config()
            delete(f);delete(l);
        end

        % Callback function: Tree2
        function Tree2CheckedNodesChanged(comp, event)
            [f,l] = matrocketloadingbar();
            checkedNodes = comp.Tree2.CheckedNodes;
            comp.draw_geometry();
            comp.draw_3dplots();
            delete(f);delete(l);
        end

        % Callback function: Tree3
        function Tree3CheckedNodesChanged(comp, event)
            [f,l] = matrocketloadingbar();
            checkedNodes = comp.Tree3.CheckedNodes;
            comp.draw_geometry();
            comp.draw_3dplots();
            delete(f);delete(l);
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(comp, event)
            [f,l] = matrocketloadingbar();
            value = comp.ScaleEditField.Value;
            comp.draw_geometry();
            comp.draw_3dplots();
            delete(f);delete(l);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(comp, event)
            [f,l] = matrocketloadingbar();
            comp.ListBox.Items     = {};
            comp.rocket_geometry   = struct();
            comp.rocket_plottables = struct();
            comp.simulations       = {};
            comp.update_simulations_list();
            comp.update_geometry_nodetree()
            delete(f);delete(l)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1351 874];
            comp.GridLayout3 = uigridlayout(comp);
            comp.GridLayout4 = uigridlayout(comp.GridLayout3);
            comp.importsimulationconfigsButton = uibutton(comp.GridLayout4, 'push');
            comp.importsimulationconfigsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @importsimulationconfigsButtonPushed, true);
            comp.importsimulationconfigsButton.Text = 'import simulation config(s)';
            comp.Button = uibutton(comp.GridLayout4, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Text = '-';
            comp.ListBox = uilistbox(comp.GridLayout4);
            comp.ClearButton = uibutton(comp.GridLayout4, 'push');
            comp.ClearButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ClearButtonPushed, true);
            comp.ClearButton.Text = 'Clear';
            comp.TabGroup = uitabgroup(comp.GridLayout3);
            comp.GeoplotTab = uitab(comp.TabGroup);
            comp.GeoplotTab.Title = 'Geoplot';
            comp.GridLayout6 = uigridlayout(comp.GeoplotTab);
            comp.Panel = uipanel(comp.GridLayout6);
            comp.GridLayout9 = uigridlayout(comp.GridLayout6);
            comp.LongitudeEditFieldLabel = uilabel(comp.GridLayout9);
            comp.LongitudeEditFieldLabel.Text = 'Longitude:';
            comp.LongitudeEditField = uieditfield(comp.GridLayout9, 'numeric');
            comp.LongitudeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LongitudeEditFieldValueChanged, true);
            comp.LatitudeEditFieldLabel = uilabel(comp.GridLayout9);
            comp.LatitudeEditFieldLabel.Text = 'Latitude:';
            comp.LatitudeEditField = uieditfield(comp.GridLayout9, 'numeric');
            comp.LatitudeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LatitudeEditFieldValueChanged, true);
            comp.PlotTab = uitab(comp.TabGroup);
            comp.PlotTab.Title = 'Plot';
            comp.GridLayout2 = uigridlayout(comp.PlotTab);
            comp.UIAxes = uiaxes(comp.GridLayout2);
            title(comp.UIAxes, 'Title')
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.Tree = uitree(comp.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            comp.Tree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @TreeCheckedNodesChanged, true);
            comp.DPlotTab = uitab(comp.TabGroup);
            comp.DPlotTab.Title = '3D Plot';
            comp.GridLayout5 = uigridlayout(comp.DPlotTab);
            comp.GridLayout10 = uigridlayout(comp.GridLayout5);
            comp.importgeometryconfigButton = uibutton(comp.GridLayout10, 'push');
            comp.importgeometryconfigButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @importgeometryconfigButtonPushed, true);
            comp.importgeometryconfigButton.Text = 'import geometry config';
            comp.Tree2 = uitree(comp.GridLayout10, 'checkbox');
            % Assign Checked Nodes
            comp.Tree2.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @Tree2CheckedNodesChanged, true);
            comp.GridLayout10_2 = uigridlayout(comp.GridLayout5);
            comp.Tree3 = uitree(comp.GridLayout10_2, 'checkbox');
            % Assign Checked Nodes
            comp.Tree3.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @Tree3CheckedNodesChanged, true);
            comp.GridLayout11 = uigridlayout(comp.GridLayout5);
            comp.UIAxes2 = uiaxes(comp.GridLayout11);
            title(comp.UIAxes2, 'Title')
            xlabel(comp.UIAxes2, 'X')
            ylabel(comp.UIAxes2, 'Y')
            zlabel(comp.UIAxes2, 'Z')
            comp.GridLayout12 = uigridlayout(comp.GridLayout11);
            comp.ScaleEditFieldLabel = uilabel(comp.GridLayout12);
            comp.ScaleEditFieldLabel.Text = 'Scale:';
            comp.ScaleEditField = uieditfield(comp.GridLayout12, 'numeric');
            comp.ScaleEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ScaleEditFieldValueChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end