classdef simulation < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        ODEsolverLabel               matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        Switch                       matlab.ui.control.Switch
        SimulationtimesLabel         matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        Button                       matlab.ui.control.Button
        ListBox                      matlab.ui.control.ListBox
        GridLayout2                  matlab.ui.container.GridLayout
        RunandsavesimulationsButton  matlab.ui.control.Button
        importinitialvaluesconfigsButton  matlab.ui.control.Button
    end

    properties (Access = public)
        last_folder
        ode_solver = @ode45;
        path
        simulations = {};
    end
    
    
    methods (Access = private)


        function load_initial_values_configs(comp)
            [files, folder] = uigetfile('*.mat','MultiSelect', 'on');
            comp.last_folder = folder;
            if ~iscell(files); files = {files}; end
            for simulation_index = 1:numel(files)
                load([folder, files{simulation_index}], "-mat", "rocket_initial_values");
                comp.simulations{end+1}                              = struct();
                comp.simulations{end  }.rocket_initial_values        = rocket_initial_values;
                comp.simulations{end  }.rocket_initial_values_file   = files{simulation_index};
                comp.simulations{end  }.rocket_initial_values_folder = folder;

            end
            comp.simulations
            comp.update_simulations_list()
        
        end


        function update_simulations_list(comp)
            comp.ListBox.Items = {};
            for simulation_index = 1:numel(comp.simulations)
    
                if isfield(comp.simulations{simulation_index}, "rocket_results")
                    comp.ListBox.Items{end+1} = [comp.simulations{simulation_index}.rocket_initial_values_file , ' (done)'];
                else
                    comp.ListBox.Items{end+1} = [comp.simulations{simulation_index}.rocket_initial_values_file , ' (ready)'];
                end
    
            end

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.path          = erase(string(mfilename("fullpath")), "\tabs\simulation");
            comp.last_folder   = erase(string(mfilename("fullpath")), "Core\MatRocketUI\tabs\simulation");
            comp.ListBox.Items = {};
        end

        % Button pushed function: importinitialvaluesconfigsButton
        function importinitialvaluesconfigsButtonPushed2(comp, event)
            comp.load_initial_values_configs();
        end

        % Button pushed function: RunandsavesimulationsButton
        function RunandsavesimulationsButtonPushed(comp, event)
           savedir = uigetdir(comp.last_folder);
           [l,f] = matrocketloadingbar();
           if isequal(string(comp.Switch.Value), "Single thread")
               for simulation_index = 1:numel(comp.simulations)
                   simulation = comp.simulations{simulation_index};
                   if ~isfield(simulation, "rocket_results")
                       rocket = simulation.rocket_initial_values;
                       rocket.t_max = comp.EditField.Value;
                       rocket.ode_solver = comp.ode_solver;
                       simulation.rocket_results = simulate_rocket(rocket);
                       filename_base = [savedir, '/',replace(simulation.rocket_initial_values_file, '.mat', '_simulated.mat')];
                       filename      = filename_base;
                       file_nr  = 0;
                       while isfile(filename)
                            filename = replace(filename_base, '.mat', ['(', char(string(file_nr)), ').mat']);
                            file_nr = file_nr+1;
                       end
                       save(filename, "-fromstruct",simulation)
                       comp.simulations{simulation_index} = simulation;
                       comp.update_simulations_list()
                   end
    
               end

           else

               ode_solver  = comp.ode_solver;
               t_max       = comp.EditField.Value;
               simulations = comp.simulations;
               parfor simulation_index = 1:numel(simulations)
                   simulation = simulations{simulation_index};
                   if ~isfield(simulation, "rocket_results")
                       rocket = simulation.rocket_initial_values;
                       rocket.t_max = t_max;
                       rocket.ode_solver = ode_solver;
                       simulation.rocket_results = simulate_rocket(rocket);
                       filename_base = [savedir, '/',replace(simulation.rocket_initial_values_file, '.mat', '_simulated.mat')];
                       filename      = filename_base;
                       file_nr  = 0;
                       while isfile(filename)
                            filename = replace(filename_base, '.mat', ['(', char(string(file_nr)), ').mat']);
                            file_nr = file_nr+1;
                       end
                       save(filename, "-fromstruct",simulation)
                   end
               end
           end
           
           comp.simulations = {};
           comp.update_simulations_list()
           delete(f);delete(l);

        end

        % Value changed function: EditField
        function EditFieldValueChanged(comp, event)
            value = comp.EditField.Value;
            if ~isequal(class(value),"double")
                errf = uifigure("Icon",comp.path+"\Logos\IMG_7900.jpg", "Name","Error");
                uialert(errf, "Dimensions must agree", "Error", "Icon", comp.path+"\Logos\IMG_7900.jpg")
                comp.EditField.Value = "";

            end
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            index = comp.ListBox.ValueIndex;
            comp.simulations = {comp.simulations{1:index-1}, comp.simulations{index+1:end}};
            comp.update_simulations_list()
        end

        % Value changed function: DropDown
        function DropDownValueChanged(comp, event)
            value = comp.DropDown.Value;
            comp.ode_solver = eval("@"+value+";");
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 999 595];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.importinitialvaluesconfigsButton = uibutton(comp.GridLayout2, 'push');
            comp.importinitialvaluesconfigsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @importinitialvaluesconfigsButtonPushed2, true);
            comp.importinitialvaluesconfigsButton.Text = 'import initial values config(s)';
            comp.RunandsavesimulationsButton = uibutton(comp.GridLayout2, 'push');
            comp.RunandsavesimulationsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunandsavesimulationsButtonPushed, true);
            comp.RunandsavesimulationsButton.Text = 'Run and save simulation (s) ►';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.ListBox = uilistbox(comp.GridLayout3);
            comp.Button = uibutton(comp.GridLayout3, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Text = '-';
            comp.EditField = uieditfield(comp.GridLayout3, 'numeric');
            comp.EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditFieldValueChanged, true);
            comp.EditField.Value = 100;
            comp.SimulationtimesLabel = uilabel(comp.GridLayout3);
            comp.SimulationtimesLabel.Text = 'Simulation time [s]:';
            comp.Switch = uiswitch(comp.GridLayout3, 'slider');
            comp.Switch.Items = {'Single thread', 'Multi thread'};
            comp.Switch.Value = 'Single thread';
            comp.DropDown = uidropdown(comp.GridLayout3);
            comp.DropDown.Items = {'ode45', 'ode23', 'ode23t', 'ode23s', 'ode23tb', 'ode113', 'ode89', 'ode78', 'ode15s', 'realtime_ode'};
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownValueChanged, true);
            comp.DropDown.Value = 'ode45';
            comp.ODEsolverLabel = uilabel(comp.GridLayout3);
            comp.ODEsolverLabel.Text = 'ODE-solver:';
            comp.Image = uiimage(comp.GridLayout3);
            comp.Image.ImageSource = 'MatRocket_logo2 transparent.png';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end