classdef MatRocketUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        TabGroup     matlab.ui.container.TabGroup
        homeTab      matlab.ui.container.Tab
        GridLayout2  matlab.ui.container.GridLayout
        Image4       matlab.ui.control.Image
        GridLayout3  matlab.ui.container.GridLayout
        Image3       matlab.ui.control.Image
        Image5       matlab.ui.control.Image
        GridLayout4  matlab.ui.container.GridLayout
        Hyperlink_4  matlab.ui.control.Hyperlink
        Hyperlink_3  matlab.ui.control.Hyperlink
        Hyperlink    matlab.ui.control.Hyperlink
        Hyperlink_2  matlab.ui.control.Hyperlink
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isdeployed()
                addpath(genpath(replace(string(mfilename("fullpath")), "MatRocketUI", "")));
            end
            %addpath(genpath(erase(path, "Simulation\MatRocketUI\")))
            %addpath(genpath("..\"))

            images                 = num2cell(dir(".\Core\MatRocketUI\Broll\"));
            images                 = images(3:end);
            rng("shuffle", "twister")
            image                  = images{randi(numel(images))};
            app.Image4.ImageSource = [image.folder,'\', image.name];
     
            tab = uitab(app.TabGroup, 'Title', "geometry",   'AutoResizeChildren','on'); geometry   (tab);
            tab = uitab(app.TabGroup, 'Title', "modelling",  'AutoResizeChildren','on'); modelling  (tab);
            tab = uitab(app.TabGroup, 'Title', "simulation", 'AutoResizeChildren','on'); simulation (tab);
            tab = uitab(app.TabGroup, 'Title', "results",    'AutoResizeChildren','on'); results    (tab);

            
            app.UIFigure.Icon = ".\Core\MatRocketUI\Logos\MatRocket_logo2 transparent.png";
            app.UIFigure.Name = "MatRocket";

        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            images                 = num2cell(dir(".\Core\MatRocketUI\Broll\"));
            images                 = images(3:end);
            rng("shuffle", "twister")
            image                  = images{randi(numel(images))};
            app.Image4.ImageSource = [image.folder,'\', image.name];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.homeTab = uitab(app.TabGroup);
            app.homeTab.Title = 'home';
            app.GridLayout2 = uigridlayout(app.homeTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Hyperlink_2 = uihyperlink(app.GridLayout4);
            app.Hyperlink_2.URL = 'https://github.com/spiggen/MatRocket';
            app.Hyperlink_2.Text = 'github';
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://discord.gg/47fC93Qpdp';
            app.Hyperlink.Text = 'discord forum';
            app.Hyperlink_3 = uihyperlink(app.GridLayout4);
            app.Hyperlink_3.URL = 'https://discord.gg/47fC93Qpdp';
            app.Hyperlink_3.Text = 'docs';
            app.Hyperlink_4 = uihyperlink(app.GridLayout4);
            app.Hyperlink_4.Text = 'wind-data';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Core', 'MatRocketUI', 'Logos', 'MatRocket_logo2 transparent.png');
            app.Image3 = uiimage(app.GridLayout3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Core', 'MatRocketUI', 'Logos', 'AESIR_purple.png');
            app.Image4 = uiimage(app.GridLayout2);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatRocketUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end